classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        AddimageButton               matlab.ui.control.Button
        saltpepperButton             matlab.ui.control.Button
        MedianButton                 matlab.ui.control.Button
        GaussianButton               matlab.ui.control.Button
        SpeckleButton                matlab.ui.control.Button
        RGBtoGrayButton              matlab.ui.control.Button
        RGBTOBWButton                matlab.ui.control.Button
        MinButton                    matlab.ui.control.Button
        MaxButton                    matlab.ui.control.Button
        DoubleButton                 matlab.ui.control.Button
        WhiteButton                  matlab.ui.control.Button
        AdditionButton               matlab.ui.control.Button
        SubtractButton               matlab.ui.control.Button
        DivisionButton               matlab.ui.control.Button
        MultiplyButton               matlab.ui.control.Button
        ComplementButton             matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        DeleteButton_2               matlab.ui.control.Button
        DeleteButton_3               matlab.ui.control.Button
        ResetAllProgramButton        matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        WeightButton                 matlab.ui.control.Button
        AverageButton                matlab.ui.control.Button
        ArithmeticoperationsLabel    matlab.ui.control.Label
        SameButton                   matlab.ui.control.Button
        FullButton                   matlab.ui.control.Button
        ValidButton                  matlab.ui.control.Button
        NoiseLabel                   matlab.ui.control.Label
        ConversionLabel              matlab.ui.control.Label
        NonLinearFiltersLabel        matlab.ui.control.Label
        OtherFiltersonlyonRGBLabel   matlab.ui.control.Label
        SmoothingLinearFiltersLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: AddimageButton
        function AddimageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Add an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'parent',app.UIAxes);
        end

        % Button pushed function: saltpepperButton
        function saltpepperButtonPushed(app, event)
            global a;
            global J;
            J=a;
            J =imnoise(J,'salt & pepper', 0.4);
            imshow(J,'parent',app.UIAxes2);
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            global a;
            global G;
            G=a;
            G =imnoise(G,'gaussian');
            imshow(G,'parent',app.UIAxes2);
        end

        % Button pushed function: SpeckleButton
        function SpeckleButtonPushed(app, event)
            global a;
            global s;
            s=a;
            s =imnoise(s,'speckle');
            imshow(s,'parent',app.UIAxes2);
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
             i = getimage(app.UIAxes2);
                if(size(i,3)==3)
                    
                    i = rgb2gray(i);
                end
             de=medfilt2(i,[3 3]);
             imshow(de,'parent',app.UIAxes3);
        end

        % Button pushed function: RGBtoGrayButton
        function RGBtoGrayButtonPushed(app, event)
            global a;
            global R22GB;
            RGB=a;
            RGB =(rgb2gray(RGB));
            imshow(RGB,'parent',app.UIAxes2);
        end

        % Button pushed function: RGBTOBWButton
        function RGBTOBWButtonPushed(app, event)
            global a;
            global blackandwhite;
            blackandwhite=a;
            blackandwhite=im2bw(blackandwhite,0.57);
            imshow(blackandwhite,'parent',app.UIAxes2);
        end

        % Button pushed function: MinButton
        function MinButtonPushed(app, event)
            noise = getimage(app.UIAxes2);
            B=zeros(size(noise));
            modifyA=padarray(noise,[1 1]);
            a=[1:3]';
            b=[1:3]';
             for x= 1:size(modifyA,1)-2
                 for y=1:size(modifyA,2)-2
                    mm=reshape(modifyA(x+a-1,y+b-1),[],1);
                     B(x,y)=min(mm);
                 end
             end
             imshow(uint8(B),'parent',app.UIAxes3);
        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
            noise = getimage(app.UIAxes2);
            B=zeros(size(noise));
            modifyA=padarray(noise,[1 1]);
            a=[1:3]';
            b=[1:3]';
            for x= 1:size(modifyA,1)-2
                for y=1:size(modifyA,2)-2
                    mx=reshape(modifyA(x+a-1,y+b-1),[],1);
                     B(x,y)=max(mx);
                end
            end
            imshow(uint8(B),'parent',app.UIAxes3);
        end

        % Button pushed function: DoubleButton
        function DoubleButtonPushed(app, event)
            a =  getimage(app.UIAxes);
            i = double(a);
            imshow(i,'parent',app.UIAxes2);
        end

        % Button pushed function: WhiteButton
        function WhiteButtonPushed(app, event)
            a =  getimage(app.UIAxes);
            i = double(a);
            imshow(i/128,'parent',app.UIAxes2);
        end

        % Button pushed function: AdditionButton
        function AdditionButtonPushed(app, event)
            n = getimage(app.UIAxes);
            n1 = imadd(n,50);
            imshow(n1,'parent',app.UIAxes2);
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            n = getimage(app.UIAxes);
            n1 = imsubtract(n,128);
            imshow(n1,'parent',app.UIAxes2);
        end

        % Button pushed function: DivisionButton
        function DivisionButtonPushed(app, event)
            n =  getimage(app.UIAxes);
            n1 = immultiply(n,0.5);
            imshow(n1,'parent',app.UIAxes2);
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            n =  getimage(app.UIAxes);
            n1 = immultiply(n,2);
            imshow(n1,'parent',app.UIAxes2);
        end

        % Button pushed function: ComplementButton
        function ComplementButtonPushed(app, event)
            n =  getimage(app.UIAxes);
            n1 = imcomplement(n);
            imshow(n1,'parent',app.UIAxes2);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.UIAxes.cla;
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
            app.UIAxes2.cla;
           
        end

        % Button pushed function: DeleteButton_3
        function DeleteButton_3Pushed(app, event)
             app.UIAxes3.cla;
        end

        % Button pushed function: ResetAllProgramButton
        function ResetAllProgramButtonPushed(app, event)
             app.UIAxes.cla;
             app.UIAxes2.cla;
             app.UIAxes3.cla;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
            msgbox('GoodBye have a nice day','Exit','replace')
            
        end

        % Button pushed function: WeightButton
        function WeightButtonPushed(app, event)
            noise = getimage(app.UIAxes2);
            f=(1/16)*[1,2,1;2,4,2;1,2,1];
            de=filter2(f,noise);
            imshow(uint8(de),'parent',app.UIAxes3);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            noise = getimage(app.UIAxes2);
            f=ones(3,3)/9;
            de=filter2(f,noise);
            imshow(uint8(de),'parent',app.UIAxes3);
        end

        % Button pushed function: SameButton
        function SameButtonPushed(app, event)
            i=getimage(app.UIAxes);
            ii=(rgb2gray(i));
            a=ones(3,3)/9;
            m=filter2(a,ii,'same');
            imshow(m,'parent',app.UIAxes2);
            
        end

        % Button pushed function: FullButton
        function FullButtonPushed(app, event)
            i=getimage(app.UIAxes);
            ii=(rgb2gray(i));
            a=ones(3,3)/9;
            m=filter2(a,ii,'full');
            imshow(m,'parent',app.UIAxes2);
        end

        % Button pushed function: ValidButton
        function ValidButtonPushed(app, event)
           i=getimage(app.UIAxes);
            ii=(rgb2gray(i));
            a=ones(3,3)/9;
            m=filter2(a,ii,'valid');
            imshow(m,'parent',app.UIAxes2); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image ');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Noisy Image');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image after removing noise');
            app.AddimageButton = uibutton(app.UIFigure, 'push');
            app.AddimageButton.ButtonPushedFcn = createCallbackFcn(app, @AddimageButtonPushed, true);
            app.AddimageButton.Text = 'Add image';
            app.saltpepperButton = uibutton(app.UIFigure, 'push');
            app.saltpepperButton.ButtonPushedFcn = createCallbackFcn(app, @saltpepperButtonPushed, true);
            app.saltpepperButton.Text = 'salt & pepper';
            app.MedianButton = uibutton(app.UIFigure, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton = uibutton(app.UIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.SpeckleButton = uibutton(app.UIFigure, 'push');
            app.SpeckleButton.ButtonPushedFcn = createCallbackFcn(app, @SpeckleButtonPushed, true);
            app.SpeckleButton.Text = 'Speckle';
            app.RGBtoGrayButton = uibutton(app.UIFigure, 'push');
            app.RGBtoGrayButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoGrayButtonPushed, true);
            app.RGBtoGrayButton.Text = 'RGB to Gray';
            app.RGBTOBWButton = uibutton(app.UIFigure, 'push');
            app.RGBTOBWButton.ButtonPushedFcn = createCallbackFcn(app, @RGBTOBWButtonPushed, true);
            app.RGBTOBWButton.Text = 'RGB TO B&W ';
            app.MinButton = uibutton(app.UIFigure, 'push');
            app.MinButton.ButtonPushedFcn = createCallbackFcn(app, @MinButtonPushed, true);
            app.MinButton.Text = 'Min';
            app.MaxButton = uibutton(app.UIFigure, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Text = 'Max';
            app.DoubleButton = uibutton(app.UIFigure, 'push');
            app.DoubleButton.ButtonPushedFcn = createCallbackFcn(app, @DoubleButtonPushed, true);
            app.DoubleButton.Text = 'Double';
            app.WhiteButton = uibutton(app.UIFigure, 'push');
            app.WhiteButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteButtonPushed, true);
            app.WhiteButton.Text = 'White';
            app.AdditionButton = uibutton(app.UIFigure, 'push');
            app.AdditionButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionButtonPushed, true);
            app.AdditionButton.Text = 'Addition';
            app.SubtractButton = uibutton(app.UIFigure, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.DivisionButton = uibutton(app.UIFigure, 'push');
            app.DivisionButton.ButtonPushedFcn = createCallbackFcn(app, @DivisionButtonPushed, true);
            app.DivisionButton.Text = 'Division';
            app.MultiplyButton = uibutton(app.UIFigure, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.ComplementButton = uibutton(app.UIFigure, 'push');
            app.ComplementButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementButton.Text = 'Complement';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DeleteButton_2 = uibutton(app.UIFigure, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.DeleteButton_3 = uibutton(app.UIFigure, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_3Pushed, true);
            app.DeleteButton_3.Text = 'Delete';
            app.ResetAllProgramButton = uibutton(app.UIFigure, 'push');
            app.ResetAllProgramButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllProgramButtonPushed, true);
            app.ResetAllProgramButton.Text = 'Reset All Program';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WeightButton = uibutton(app.UIFigure, 'push');
            app.WeightButton.ButtonPushedFcn = createCallbackFcn(app, @WeightButtonPushed, true);
            app.WeightButton.Text = 'Weight';
            app.AverageButton = uibutton(app.UIFigure, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.ArithmeticoperationsLabel = uilabel(app.UIFigure);
            app.ArithmeticoperationsLabel.Text = ' Arithmetic operations';
            app.SameButton = uibutton(app.UIFigure, 'push');
            app.SameButton.ButtonPushedFcn = createCallbackFcn(app, @SameButtonPushed, true);
            app.SameButton.Text = 'Same';
            app.FullButton = uibutton(app.UIFigure, 'push');
            app.FullButton.ButtonPushedFcn = createCallbackFcn(app, @FullButtonPushed, true);
            app.FullButton.Text = 'Full';
            app.ValidButton = uibutton(app.UIFigure, 'push');
            app.ValidButton.ButtonPushedFcn = createCallbackFcn(app, @ValidButtonPushed, true);
            app.ValidButton.Text = 'Valid';
            app.NoiseLabel = uilabel(app.UIFigure);
            app.NoiseLabel.Text = 'Noise';
            app.ConversionLabel = uilabel(app.UIFigure);
            app.ConversionLabel.Text = ' Conversion';
            app.NonLinearFiltersLabel = uilabel(app.UIFigure);
            app.NonLinearFiltersLabel.Text = ' Non Linear Filters';
            app.OtherFiltersonlyonRGBLabel = uilabel(app.UIFigure);
            app.OtherFiltersonlyonRGBLabel.Text = {'Other Filters'; ' "only on RGB"'};
            app.SmoothingLinearFiltersLabel = uilabel(app.UIFigure);
            app.SmoothingLinearFiltersLabel.Text = ' Smoothing Linear Filters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end